classdef control_loop_visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        ControlsGridLayout  matlab.ui.container.GridLayout
        PositionSlider      matlab.ui.control.Slider
        VelocitySlider      matlab.ui.control.Slider
        PositionSpinner     matlab.ui.control.Spinner
        VelocitySpinner     matlab.ui.control.Spinner
        PositionText        matlab.ui.control.EditField
        VelocityText        matlab.ui.control.EditField
        RightPanel          matlab.ui.container.Panel
        GraphGridLayout     matlab.ui.container.GridLayout
        PositionUIAxes      matlab.ui.control.UIAxes
        VelocityUIAxes      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        pos = 30; % Initial position value before the startupFcn is called
        vel = 0; % Initial position value before the startupFcn is called
        ds = -1;
    end
    
    properties (Access = private)
        PositionGraph_r % Position reference
        PositionGraph_y % Position output
        VelocityGraph_r % Velocity reference
        VelocityGraph_y % Velocity output
    end
    
    methods (Access = public)
        
        function updateGraphs(app, pos_graph, vel_graph)
            % Update Position Graph
            addpoints(app.PositionGraph_r, pos_graph.t, pos_graph.r); % Position Reference
            addpoints(app.PositionGraph_y, pos_graph.t, pos_graph.y); % Position Output
            
            % Update Velocity Graph
            addpoints(app.VelocityGraph_r, vel_graph.t, vel_graph.r); % Velocity Reference
            addpoints(app.VelocityGraph_y, vel_graph.t, vel_graph.y); % Velocity Output
            
            drawnow
        end
        
        function updateControls(app, pos, vel)
            app.PositionSlider.Value = pos; % Change the position slider value
            app.VelocitySlider.Value = vel; % Change the velocity slider value
            app.PositionSpinner.Value = pos; % Change the position spinner value
            app.VelocitySpinner.Value = vel; % Change the velocity spinner value
        end
        
        function [reference] = getReference(app)
            reference = [deg2rad(app.pos); deg2rad(app.vel)];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ds)
            app.ds;
            
            % Update position and velocity
            app.pos = ds.dataArea.pos;
            app.vel = ds.dataArea.vel;
            
            updateControls(app, app.pos, app.vel);
            
            ds.dataArea.pos = 90;
            
            % Create animated lines in graphs
            app.PositionGraph_r = animatedline(app.PositionUIAxes, NaN, NaN);
            app.PositionGraph_y = animatedline(app.PositionUIAxes, NaN, NaN);
            
            app.VelocityGraph_r = animatedline(app.VelocityUIAxes, NaN, NaN);
            app.VelocityGraph_y = animatedline(app.VelocityUIAxes, NaN, NaN);
            
            updateGraphs(app, ds.dataArea.pos_graph, ds.dataArea.vel_graph);
        end

        % Value changing function: PositionSlider
        function PositionSliderValueChanging(app, event)
            changingValue = event.Value;
            
            app.pos = changingValue; % Store in global
            app.PositionSpinner.Value = changingValue; % Change the position spinner value
            
            % Change reference position value
            ds.dataArea.pos = changingValue;
            
        end

        % Value changed function: PositionSlider
        function PositionSliderValueChanged(app, event)
            value = app.PositionSlider.Value;
            
            app.pos = value; % Store in global
            app.PositionSpinner.Value = value; % Change the position spinner value
            
            % Change reference position value
            ds.dataArea.pos = value;
            
        end

        % Value changing function: VelocitySlider
        function VelocitySliderValueChanging(app, event)
            changingValue = event.Value;
            
            app.vel = changingValue; % Store in global
            app.VelocitySpinner.Value = changingValue; % Change the velocity spinner value
            
            % Change reference velocity value
            ds.dataArea.vel = changingValue;
            
        end

        % Value changed function: VelocitySlider
        function VelocitySliderValueChanged(app, event)
            value = app.VelocitySlider.Value;
            
            app.vel = value; % Store in global
            app.VelocitySpinner.Value = value; % Change the velocity spinner value
            
            % Change reference velocity value
            ds.dataArea.vel = value;
            
        end

        % Value changed function: PositionSpinner
        function PositionSpinnerValueChanged(app, event)
            value = app.PositionSpinner.Value;
            
            app.pos = value; % Store in global
            app.PositionSlider.Value = value; % Change the position slider value
            
            % Change reference position value
            ds.dataArea.pos = value;
            
        end

        % Value changed function: VelocitySpinner
        function VelocitySpinnerValueChanged(app, event)
            value = app.VelocitySpinner.Value;
            
            app.vel = value; % Store in global
            app.VelocitySlider.Value = value; % Change the velocity slider value
            
            % Change reference velocity value
            ds.dataArea.vel = value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {748, 748};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {321, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ControlsGridLayout = uigridlayout(app.LeftPanel);
            app.PositionSlider = uislider(app.ControlsGridLayout);
            app.PositionSlider.Limits = [0 180];
            app.PositionSlider.ValueChangedFcn = createCallbackFcn(app, @PositionSliderValueChanged, true);
            app.PositionSlider.ValueChangingFcn = createCallbackFcn(app, @PositionSliderValueChanging, true);
            app.VelocitySlider = uislider(app.ControlsGridLayout);
            app.VelocitySlider.Limits = [-90 90];
            app.VelocitySlider.ValueChangedFcn = createCallbackFcn(app, @VelocitySliderValueChanged, true);
            app.VelocitySlider.ValueChangingFcn = createCallbackFcn(app, @VelocitySliderValueChanging, true);
            app.PositionSpinner = uispinner(app.ControlsGridLayout);
            app.PositionSpinner.Limits = [0 270];
            app.PositionSpinner.RoundFractionalValues = 'on';
            app.PositionSpinner.ValueChangedFcn = createCallbackFcn(app, @PositionSpinnerValueChanged, true);
            app.VelocitySpinner = uispinner(app.ControlsGridLayout);
            app.VelocitySpinner.Limits = [-360 360];
            app.VelocitySpinner.RoundFractionalValues = 'on';
            app.VelocitySpinner.ValueChangedFcn = createCallbackFcn(app, @VelocitySpinnerValueChanged, true);
            app.PositionText = uieditfield(app.ControlsGridLayout, 'text');
            app.PositionText.Value = 'Position (deg)';
            app.VelocityText = uieditfield(app.ControlsGridLayout, 'text');
            app.VelocityText.Value = 'Velocity (deg/s)';
            app.RightPanel = uipanel(app.GridLayout);
            app.GraphGridLayout = uigridlayout(app.RightPanel);
            app.PositionUIAxes = uiaxes(app.GraphGridLayout);
            title(app.PositionUIAxes, '')
            xlabel(app.PositionUIAxes, 'Time (s)')
            ylabel(app.PositionUIAxes, 'Position (deg)')
            app.PositionUIAxes.XLim = [0 10];
            app.PositionUIAxes.YLim = [-60 360];
            app.PositionUIAxes.YTick = [-60 0 60 120 180 240 300 360];
            app.PositionUIAxes.YTickLabel = {'-60'; '0'; '60'; '120'; '180'; '240'; '300'; '360'};
            app.VelocityUIAxes = uiaxes(app.GraphGridLayout);
            title(app.VelocityUIAxes, '')
            xlabel(app.VelocityUIAxes, 'Time (s)')
            ylabel(app.VelocityUIAxes, 'Velocity (deg/s)')
            app.VelocityUIAxes.XLim = [0 10];
            app.VelocityUIAxes.YLim = [-90 360];
            app.VelocityUIAxes.YTick = [-60 0 60 120 180 240 300 360];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = control_loop_visualizer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end